classdef app_dip_final_Dana < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Image_Processing_AppUIFigure   matlab.ui.Figure
        RevertButton                   matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        GeneralTab                     matlab.ui.container.Tab
        FlippingWaysButtonGroup        matlab.ui.container.ButtonGroup
        UpsideDownButton               matlab.ui.control.RadioButton
        HorizontalButton               matlab.ui.control.RadioButton
        NoneButton_4                   matlab.ui.control.RadioButton
        Slider_4                       matlab.ui.control.Slider
        Slider_3                       matlab.ui.control.Slider
        Slider_2                       matlab.ui.control.Slider
        ColsEditField                  matlab.ui.control.NumericEditField
        RowsEditField                  matlab.ui.control.NumericEditField
        SelectActionDropDown           matlab.ui.control.DropDown
        ViewChannelonlyforRGBimagesButtonGroup  matlab.ui.container.ButtonGroup
        BlueButton                     matlab.ui.control.RadioButton
        GreenButton                    matlab.ui.control.RadioButton
        RedButton                      matlab.ui.control.RadioButton
        PointProcessingTab             matlab.ui.container.Tab
        InsertValuesPanel              matlab.ui.container.Panel
        TextArea_3                     matlab.ui.control.TextArea
        outputLow                      matlab.ui.control.NumericEditField
        LowLabel_2                     matlab.ui.control.Label
        inputhigh                      matlab.ui.control.NumericEditField
        HighLabel_2                    matlab.ui.control.Label
        GammaEditField                 matlab.ui.control.NumericEditField
        GammaEditFieldLabel            matlab.ui.control.Label
        outputHigh                     matlab.ui.control.NumericEditField
        HighLabel                      matlab.ui.control.Label
        inputlow                       matlab.ui.control.NumericEditField
        LowLabel                       matlab.ui.control.Label
        TextArea_2                     matlab.ui.control.TextArea
        TextArea                       matlab.ui.control.TextArea
        SelectOperationButtonGroup     matlab.ui.container.ButtonGroup
        HistEqualizationButton         matlab.ui.control.RadioButton
        AdjustContrastGammaButton      matlab.ui.control.RadioButton
        AdjustContrastButton           matlab.ui.control.RadioButton
        NoneButton_3                   matlab.ui.control.RadioButton
        GammaCorrectionButton          matlab.ui.control.RadioButton
        NeighbourhoodProcessingTab     matlab.ui.container.Tab
        AlphaValueSlider               matlab.ui.control.Slider
        NoiseTypeButtonGroup           matlab.ui.container.ButtonGroup
        PeriodicButton                 matlab.ui.control.RadioButton
        SaltPepperButton               matlab.ui.control.RadioButton
        SpeckleButton                  matlab.ui.control.RadioButton
        GaussianButton                 matlab.ui.control.RadioButton
        NoneButton                     matlab.ui.control.RadioButton
        Slider2_2                      matlab.ui.control.Slider
        FilterSizeButtonGroup          matlab.ui.container.ButtonGroup
        NoneButton_2                   matlab.ui.control.RadioButton
        SmallButton                    matlab.ui.control.RadioButton
        LargeButton                    matlab.ui.control.RadioButton
        Slider                         matlab.ui.control.Slider
        DropDown                       matlab.ui.control.DropDown
        TransformationsTab             matlab.ui.container.Tab
        FourierTransformDropDown       matlab.ui.control.DropDown
        FourierTransformDropDownLabel  matlab.ui.control.Label
        ApplyButton                    matlab.ui.control.Button
        ExitButton                     matlab.ui.control.Button
        Gauge                          matlab.ui.control.Gauge
        UploadImageButton              matlab.ui.control.Button
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img % Description
        img2 % Description
        complementimg % Description
        imgflipped
        fourierimg
        inp1
        inp2
        inp3
        inp4
        adjustimg
        bwimg
        blurimg
        imgnoise
        revertedimg
        unsharpimg
        nonlinimg
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FlippingWaysButtonGroup.Visible = 'off';

            app.Slider_3.Visible = 'off';
            app.Slider_4.Visible = 'off';
            app.Slider_2.Visible = 'off';

            app.TextArea_3.Visible = 'off';
            app.UIAxes2.Visible = 'off';
            app.UIAxes3.Visible = 'off';

            app.InsertValuesPanel.Visible = 'off';
            app.Gauge.Visible = 'off';
            app.RowsEditField.Visible = 'off';
            app.RevertButton.Visible = 'off';

            app.AlphaValueSlider.Visible = 'off';
            app.NoiseTypeButtonGroup.Visible = 'off';

            app.Slider.Visible = 'off';
            app.FilterSizeButtonGroup.Visible = 'off';

            app.RowsEditField.Visible = 'off';
            app.ColsEditField.Visible = 'off';

            app.Slider2_2.Visible = 'off';
            app.ViewChannelonlyforRGBimagesButtonGroup.Visible = 'off';
            app.Gauge.Visible = 'off';
            app.UIAxes2.Visible = 'off';

            app.outputLow.Visible = 'off';
            app.inputlow.Visible = 'off';
            app.inputhigh.Visible = 'off';
            app.outputHigh.Visible = 'off';
            app.TextArea.Visible = 'off';
            app.TextArea_2.Visible = 'off';
            app.GammaEditField.Visible = 'off';

            app.LowLabel_2.Visible = 'off';
            app.HighLabel_2.Visible = 'off';
            app.LowLabel.Visible = 'off';
            app.HighLabel.Visible = 'off';
            app.GammaEditFieldLabel.Visible = 'off';

        end

        % Button pushed function: UploadImageButton
        function Upload(app, event)
           
            [filename, pathname] = uigetfile('*.*');

            if isequal(filename,0) %if we cancel img selection
                figure(app.Image_Processing_AppUIFigure);
                return;
            end

            figure(app.Image_Processing_AppUIFigure);
            filename=strcat(pathname,filename);
            app.img = imread(filename);
            
            app.img2 = im2gray(app.img);
            app.complementimg = app.img2;
            app.imgflipped = app.img2;
            app.fourierimg = app.img2;
            app.adjustimg = app.img2;
            app.bwimg = app.img2;
            app.blurimg = app.img2;
            app.imgnoise = app.img2;
            app.revertedimg = app.img2;
            app.unsharpimg = app.img2;
            app.nonlinimg = app.img2;

            imshow(app.img2,'Parent',app.UIAxes);
        
        end

        % Button pushed function: ExitButton
        function Exit(app, event)
            closereq()
        end

        % Value changed function: SelectActionDropDown
        function SelectActionDropDownMenu(app, event)
            %General
            value = app.SelectActionDropDown.Value;

                switch value

                    case 'Flip'
                        app.FlippingWaysButtonGroup.Visible = 'on';

                        app.ViewChannelonlyforRGBimagesButtonGroup.Visible = 'off';
                        app.Slider_4.Visible = 'off';
                        app.Slider_3.Visible = 'off';
                        app.Slider_2.Visible = 'off';

                    case 'Black & White'
                        app.FlippingWaysButtonGroup.Visible = 'off';
                        app.ViewChannelonlyforRGBimagesButtonGroup.Visible = 'off';

                        app.Slider_4.Visible = 'off';
                        app.Slider_3.Visible = 'off';
                        app.Slider_2.Visible = 'off';


                    case 'Complement'
                        app.FlippingWaysButtonGroup.Visible = 'off';
                        app.Slider_4.Visible = 'off';
                        app.Slider_3.Visible = 'off';
                        app.Slider_2.Visible = 'off';
                        
                        app.ViewChannelonlyforRGBimagesButtonGroup.Visible = 'off';

                    
                    case 'View Channel'
                        app.FlippingWaysButtonGroup.Visible = 'off';
                        app.Slider_4.Visible = 'on';
                        app.Slider_3.Visible = 'on';
                        app.Slider_2.Visible = 'on';

                        app.ViewChannelonlyforRGBimagesButtonGroup.Visible = 'on';
                                        
                    case 'View Size'
                        app.FlippingWaysButtonGroup.Visible = 'off';
                        app.Slider_4.Visible = 'off';
                        app.Slider_3.Visible = 'off';
                        app.Slider_2.Visible = 'off';
                        
                        app.RowsEditField.Visible = 'on';
                        app.ColsEditField.Visible = 'on';

                        [r,c] = size(app.img2);
                        app.RowsEditField.Value = r;
                        app.ColsEditField.Value = c;                    

                end
 
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
         
            task = app.SelectActionDropDown.Value;
            fttasks = app.FourierTransformDropDown.Value;
            neightasks = app.DropDown.Value;

            pointv = app.SelectOperationButtonGroup.SelectedObject;
            pointtasks = pointv.Text;


            activeTab = app.TabGroup.SelectedTab;
            tabTitle = activeTab.Title;

            app.UIAxes2.Visible = 'off';
            app.UIAxes3.Visible = 'off';

            switch tabTitle

                case 'General'
        
                            if (task == "Flip")
                                flip = app.FlippingWaysButtonGroup.SelectedObject.Text;

                                switch flip

                                    case "Horizontal"
                                        app.RevertButton.Visible = 'on';
        
                                        c = app.imgflipped;
                                        [rows, cols] = size(c); %#ok<ASGLU>
                                        c = c(:, cols: -1:1); 
                                        imshow(c,'Parent', app.UIAxes_2);

                                    case "Upside Down"
                                        app.RevertButton.Visible = 'on';

                                        c = app.imgflipped;
                                        [rows, cols] = size(c); 

                                        newHeight = 256; 
                                        aspectRatio = newHeight / rows;
                                        newWidth = round(aspectRatio * cols);
                                        
                                        % Resize the image in order to flip
                                        resizedImage = imresize(c, [newHeight, newWidth]);
                                        
                                        % Flip the resized image upside down
                                        res = resizedImage(end:-1:1, :);

                                        imshow(res,'Parent', app.UIAxes_2);

                                end
                            end

                            if (task == "Black & White")
                                app.RevertButton.Visible = 'on';

                                app.bwimg = im2bw(app.bwimg); %#ok<IM2BW>
                                imshow(app.bwimg, 'Parent',app.UIAxes_2)
                         
                            end
                      
                            if (task == "View Channel")
                           
                                app.ViewChannelonlyforRGBimagesButtonGroup.Visible = 'on';
                                val = app.ViewChannelonlyforRGBimagesButtonGroup.SelectedObject.Text;
                                c = app.img;
                
                                switch val
                
                                    case 'Red'
                                        app.Slider_2.Visible = 'on';

                                        c(:,:,3) = 0;
                                        c(:,:,2) = 0;
                                        
                                        imshow(c, 'Parent',app.UIAxes_2)
                
                                    case 'Green'
                                        c(:,:,1) = 0;
                                        c(:,:,3) = 0;
                                        imshow(c, 'Parent',app.UIAxes_2)
                                    case 'Blue'
                                        c(:,:,1) = 0;
                                        c(:,:,2) = 0;
                                        imshow(c, 'Parent',app.UIAxes_2)
                                end
                    
                           end
                             
                           if (task == "Complement")
                                app.RevertButton.Visible = 'on';
                                
                                app.complementimg = 255 - app.complementimg;
                                imshow(app.complementimg,'Parent', app.UIAxes_2);
                           end
                
                          
                case 'Point Processing'
                    app.RevertButton.Visible = 'on';
                    switch pointtasks
                        case "Adjust Contrast"

                            v1 = app.inputlow.Value;
                            v2 = app.inputhigh.Value;
                            v3 = app.outputLow.Value;
                            v4 = app.outputHigh.Value;
                
                           
                           c = app.adjustimg;
                           c = imadjust(c,[v1 v2],[v4 v3]);
                           imshow(c, 'Parent',app.UIAxes_2)
                    

                        case "Adjust Contrast + Gamma"

                          v1 = app.inputlow.Value;
                          v2 = app.inputhigh.Value;
                          v3 = app.outputLow.Value;
                          v4 = app.outputHigh.Value;
                          v5 = app.GammaEditField.Value;
        
                          c2 = app.adjustimg;
        
                          c2 = imadjust(c2,[v1 v2],[v4 v3],v5);
                          imshow(c2, 'Parent',app.UIAxes_2)


                        case "Hist Equalization"

                           app.UIAxes2.Visible = 'on';
                           app.UIAxes3.Visible = 'on';
                           app.InsertValuesPanel.Visible = 'off';

                           c3 = app.adjustimg;
                           equalized = histeq(c3);
                           
                           histogram(app.UIAxes2, app.adjustimg);
                           histogram(app.UIAxes3, equalized);

                           imshow(equalized, 'Parent',app.UIAxes_2)
                    end

                case 'Neighbourhood Processing'

                    app.RevertButton.Visible = 'on';
                    if (neightasks == "Blurring")
                            app.FilterSizeButtonGroup.Visible = 'on';
                   
                    end

                    if (neightasks == "Unsharp Masking")

                           app.AlphaValueSlider.Visible = 'on';
                    end

                    if (neightasks == "Non-Linear Filter")
                            c = app.nonlinimg;
                            Cmax = nlfilter(c, [3 3], 'max(x(:))'); %nonlin
                            imshow(Cmax,'Parent',app.UIAxes_2)
                    end

                    if (neightasks == "Edge Detection")
                            c = app.img2;

                            f3 = fspecial('laplacian'); 
                            cf3 = filter2(f3,c,"same");
                            imshow(cf3/100,'Parent',app.UIAxes_2)
                    end

                case 'Transformations'
                       app.RevertButton.Visible = 'on';
                       if (fttasks == "FT")
                          fi = fft2(app.fourierimg);
                        
                          fftshow = mat2gray(log(1+abs(fi)));
                          imshow(fftshow, 'Parent',app.UIAxes_2)
            
                       end
            
                       if (fttasks == "FT Shift")
                           cmf = fftshift(fft2(app.fourierimg));
                           fftshow = mat2gray(log(1+abs(cmf)));
                           imshow(fftshow, 'Parent',app.UIAxes_2)
            
                       end
            
                       if (fttasks == "FT Inverse")
                           ifi = ifft2(fft2(app.fourierimg));
                           imshow(ifi/255,'Parent', app.UIAxes_2);

                       end
             end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            %neighbourhood processing

            val = app.DropDown.Value;

            if (val == "Blurring")
                 
                 app.AlphaValueSlider.Visible = 'off';
                 app.FilterSizeButtonGroup.Visible = 'on';
                 app.NoiseTypeButtonGroup.Visible = 'off';
               
            end

            if (val == "Unsharp Masking")
                app.AlphaValueSlider.Visible = 'on';
                app.FilterSizeButtonGroup.Visible = 'off';
                app.Slider2_2.Visible = 'off';
                app.Slider.Visible = 'off';
                app.NoiseTypeButtonGroup.Visible = 'off';
            end
           
            if (val == "Add Noise")
                app.AlphaValueSlider.Visible = 'on';
                app.NoiseTypeButtonGroup.Visible = 'on';

                app.FilterSizeButtonGroup.Visible = 'off';
                app.Slider2_2.Visible = 'off';
                app.Slider.Visible = 'off';
            end

            if (val == "Edge Detection")
                app.AlphaValueSlider.Visible = 'off';
                app.NoiseTypeButtonGroup.Visible = 'off';

                app.FilterSizeButtonGroup.Visible = 'off';
                app.Slider2_2.Visible = 'off';
                app.Slider.Visible = 'off';
            end
            
            if (val == "Non-Linear Filter")
                app.AlphaValueSlider.Visible = 'off';
                app.NoiseTypeButtonGroup.Visible = 'off';

                app.FilterSizeButtonGroup.Visible = 'off';
                app.Slider2_2.Visible = 'off';
                app.Slider.Visible = 'off';
            end
            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.Slider.Visible = 'on';
            app.Slider2_2.Visible = 'off';

            f = fspecial("average",round(value));  %filter
            c = app.blurimg;
            c = filter2(f, c,'same');
            imshow(c/255, 'Parent', app.UIAxes_2);

        end

        % Selection changed function: FilterSizeButtonGroup
        function FilterSizeButtonGroupSelectionChanged(app, event)
            
            if app.SmallButton.Value == 1
                    app.Slider.Visible = 'on';
                    app.Slider2_2.Visible = 'off';

            elseif app.LargeButton.Value == 1
                 app.Slider2_2.Visible = 'on';
                 app.Slider.Visible = 'off';
            end
            
        end

        % Selection changed function: NoiseTypeButtonGroup
        function NoiseTypeButtonGroupSelectionChanged(app, event)
            app.AlphaValueSlider.Visible = 'on';
            app.RevertButton.Visible = 'on';

            c = app.imgnoise;
            alphav = app.AlphaValueSlider.Value;


            if app.SaltPepperButton.Value == 1
                
                n = imnoise(c,'salt & pepper',alphav);
                imshow(n,'Parent',app.UIAxes_2)

            elseif app.SpeckleButton.Value == 1
                    n = imnoise(c,'speckle',alphav);
                    imshow(n,'Parent',app.UIAxes_2)
            
                
            elseif app.GaussianButton.Value == 1
                    n = imnoise(c,'gaussian',alphav);
                    imshow(n,'Parent',app.UIAxes_2)
            

            elseif app.PeriodicButton.Value == 1
                    [r,col] = size(c); %#ok<ASGLU>
                    [x,y] = meshgrid(1:col,1:col);
                    p=sin(x/3+ y/5)+1;

                if ~isequal(size(p), size(c))
                     p = imresize(p, size(c));
                end

                                
                cp=(im2double(c)+p/2)/2;
                imshow(cp,'Parent',app.UIAxes_2)
            end
        end

        % Button pushed function: RevertButton
        function RevertButtonPushed(app, event)
            imshow(app.revertedimg, 'Parent',app.UIAxes_2)
        end

        % Value changed function: AlphaValueSlider
        function AlphaValueSliderValueChanged(app, event)
            alphav = app.AlphaValueSlider.Value;
            val = app.DropDown.Value;

            if val == "Unsharp Masking"
                app.UIAxes2.Visible = 'off';
                app.UIAxes3.Visible = 'off';

                
                f2=fspecial('unsharp',alphav);
                c = app.unsharpimg;
    
                c = filter2(f2, c);
    
                imshow(c/255 , 'Parent', app.UIAxes_2);
            end
            
            if val == "Add Noise"
                
                app.UIAxes2.Visible = 'off';
                app.UIAxes3.Visible = 'off';
                c = app.imgnoise;

                if app.SaltPepperButton.Value == 1
                    
                    n = imnoise(c,'salt & pepper',alphav);
                    imshow(n,'Parent',app.UIAxes_2)
    
                elseif app.SpeckleButton.Value == 1
                        n = imnoise(c,'speckle',alphav);
                        imshow(n,'Parent',app.UIAxes_2)
                
                    
                elseif app.GaussianButton.Value == 1
                        n = imnoise(c,'gaussian',alphav);
                        imshow(n,'Parent',app.UIAxes_2)
                
    
                end
            end
        end

        % Selection changed function: SelectOperationButtonGroup
        function SelectOperationButtonGroupSelectionChanged(app, event)
            
            if app.AdjustContrastButton.Value == 1
                app.TextArea_3.Visible = 'off';
                app.UIAxes3.Visible = 'off';
                app.UIAxes2.Visible = 'off';

                app.InsertValuesPanel.Visible = 'on';
                app.outputLow.Visible = 'on';
                app.inputlow.Visible = 'on';
                app.inputhigh.Visible = 'on';
                app.outputHigh.Visible = 'on';
    
                app.TextArea.Visible = 'on';
                app.TextArea_2.Visible = 'on';
    
                app.GammaEditField.Visible = 'off';
                app.GammaEditFieldLabel.Visible = 'off';
    
                app.LowLabel_2.Visible = 'on';
                app.HighLabel_2.Visible = 'on';
                app.LowLabel.Visible = 'on';
                app.HighLabel.Visible = 'on';
             
            end

            if app.GammaCorrectionButton.Value == 1
                app.UIAxes3.Visible = 'off';
                app.UIAxes2.Visible = 'off';

                app.TextArea_3.Visible = 'on';

                app.InsertValuesPanel.Visible = 'on';

                app.outputLow.Visible = 'off';
                app.inputlow.Visible = 'off';
                app.inputhigh.Visible = 'off';
                app.outputHigh.Visible = 'off';
                app.TextArea.Visible = 'off';
                app.TextArea_2.Visible = 'off';
    
                app.LowLabel_2.Visible = 'off';
                app.HighLabel_2.Visible = 'off';
                app.LowLabel.Visible = 'off';
                app.HighLabel.Visible = 'off';
    
                app.GammaEditField.Visible = 'on';
                app.GammaEditFieldLabel.Visible = 'on';
               

            end
            
            if app.HistEqualizationButton.Value ==1
               

                app.TextArea_3.Visible = 'off';

                app.InsertValuesPanel.Visible = 'off';
                
                app.outputLow.Visible = 'off';
                app.inputlow.Visible = 'off';
                app.inputhigh.Visible = 'off';
                app.outputHigh.Visible = 'off';
                app.TextArea.Visible = 'off';
                app.TextArea_2.Visible = 'off';
                app.GammaEditField.Visible = 'off';

                app.GammaEditFieldLabel.Visible = 'off';
    
                app.LowLabel_2.Visible = 'off';
                app.HighLabel_2.Visible = 'off';
                app.LowLabel.Visible = 'off';
                app.HighLabel.Visible = 'off';


            end
            if app.AdjustContrastGammaButton.Value==1
                app.TextArea_3.Visible = 'off';

                app.TextArea_2.Visible = 'off';
                app.UIAxes3.Visible = 'off';
                app.UIAxes2.Visible = 'off';

                app.InsertValuesPanel.Visible = 'on';

                app.outputLow.Visible = 'on';
                app.inputlow.Visible = 'on';
                app.inputhigh.Visible = 'on';
                app.outputHigh.Visible = 'on';
                app.TextArea.Visible = 'on';
                app.TextArea_2.Visible = 'on';
    
                app.GammaEditField.Visible = 'on';
                app.GammaEditFieldLabel.Visible = 'on';
    
                app.LowLabel_2.Visible = 'on';
                app.HighLabel_2.Visible = 'on';
                app.LowLabel.Visible = 'on';
                app.HighLabel.Visible = 'on';
    
              
            end
        end

        % Value changed function: GammaEditField
        function GammaEditFieldValueChanged(app, event)

           app.Gauge.Visible = 'on';
           v = app.SelectOperationButtonGroup.SelectedObject;
           button = v.Text;
           v5 = app.GammaEditField.Value;

           
           app.Gauge.Value = v5;

           switch button
               case 'Gamma Correction'
                   c1 = app.adjustimg;
                   c1 = imadjust(c1,[],[],v5);
                   imshow(c1, 'Parent',app.UIAxes_2)
        
           end
        end

        % Value changed function: Slider2_2
        function Slider2_2ValueChanged(app, event)
            value = app.Slider2_2.Value;
            app.Slider2_2.Visible = 'on';
            app.Slider.Visible = 'off';
           
            f = fspecial("average",round(value));  %filter
            c = app.blurimg;
            c = filter2(f, c);
            imshow(c/255, 'Parent', app.UIAxes_2);

        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            red_value = app.Slider_2.Value;
            green_value = app.Slider_3.Value;
            blue_value = app.Slider_4.Value;

            c = app.img;
            r=c(:,:,1);
            g=c(:,:,2); 
            b=c(:,:,3);
            
            r1=r+red_value; 
            g1=g+green_value;
            b1=b+blue_value;

            thisp=cat(3,r1,g1,b1);
            %thisp = r1+g+b;
            imshow(thisp,'Parent',app.UIAxes_2)
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            red_value = app.Slider_2.Value;
            green_value = app.Slider_3.Value;
            blue_value = app.Slider_4.Value;

            c = app.img;
            r=c(:,:,1);
            g=c(:,:,2); 
            b=c(:,:,3);
            

            r1=r+red_value; 
            g1=g+green_value;
            b1=b+blue_value;

            thisp=cat(3,r1,g1,b1);
            imshow(thisp,'Parent',app.UIAxes_2)
        end

        % Value changed function: Slider_4
        function Slider_4ValueChanged(app, event)
            red_value = app.Slider_2.Value;
            green_value = app.Slider_3.Value;
            blue_value = app.Slider_4.Value;

            c = app.img;

            r=c(:,:,1);
            g=c(:,:,2); 
            b=c(:,:,3);
            
            r1=r+red_value; 
            g1=g+green_value;
            b1=b+blue_value;

            thisp=cat(3,r1,g1,b1);

            imshow(thisp,'Parent',app.UIAxes_2)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Image_Processing_AppUIFigure = uifigure('Visible', 'off');
            app.Image_Processing_AppUIFigure.Name = 'Image_Processing_App';
            app.UIAxes = uiaxes(app.Image_Processing_AppUIFigure);
            title(app.UIAxes, 'Original Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.Image_Processing_AppUIFigure);
            title(app.UIAxes_2, 'Processed Image')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes2 = uiaxes(app.Image_Processing_AppUIFigure);
            title(app.UIAxes2, 'Histogram Before')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.Image_Processing_AppUIFigure);
            title(app.UIAxes3, 'Histogram After')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UploadImageButton = uibutton(app.Image_Processing_AppUIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @Upload, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.Gauge = uigauge(app.Image_Processing_AppUIFigure, 'circular');
            app.Gauge.Limits = [0 10];
            app.Gauge.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.ExitButton = uibutton(app.Image_Processing_AppUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @Exit, true);
            app.ExitButton.Text = 'Exit';
            app.ApplyButton = uibutton(app.Image_Processing_AppUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.TabGroup = uitabgroup(app.Image_Processing_AppUIFigure);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.ViewChannelonlyforRGBimagesButtonGroup = uibuttongroup(app.GeneralTab);
            app.ViewChannelonlyforRGBimagesButtonGroup.Title = 'View Channel [only for RGB images]';
            app.RedButton = uiradiobutton(app.ViewChannelonlyforRGBimagesButtonGroup);
            app.RedButton.Text = 'Red';
            app.RedButton.Value = true;
            app.GreenButton = uiradiobutton(app.ViewChannelonlyforRGBimagesButtonGroup);
            app.GreenButton.Text = 'Green';
            app.BlueButton = uiradiobutton(app.ViewChannelonlyforRGBimagesButtonGroup);
            app.BlueButton.Text = 'Blue';
            app.SelectActionDropDown = uidropdown(app.GeneralTab);
            app.SelectActionDropDown.Items = {'Black & White', 'View Channel', 'Complement', 'Flip', 'View Size'};
            app.SelectActionDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectActionDropDownMenu, true);
            app.SelectActionDropDown.Value = 'Black & White';
            app.RowsEditField = uieditfield(app.GeneralTab, 'numeric');
            app.ColsEditField = uieditfield(app.GeneralTab, 'numeric');
            app.Slider_2 = uislider(app.GeneralTab);
            app.Slider_2.Limits = [0 255];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_3 = uislider(app.GeneralTab);
            app.Slider_3.Limits = [0 255];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Slider_4 = uislider(app.GeneralTab);
            app.Slider_4.Limits = [0 255];
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @Slider_4ValueChanged, true);
            app.FlippingWaysButtonGroup = uibuttongroup(app.GeneralTab);
            app.FlippingWaysButtonGroup.Title = 'Flipping Ways:';
            app.NoneButton_4 = uiradiobutton(app.FlippingWaysButtonGroup);
            app.NoneButton_4.Visible = 'off';
            app.NoneButton_4.Text = 'None';
            app.NoneButton_4.Value = true;
            app.HorizontalButton = uiradiobutton(app.FlippingWaysButtonGroup);
            app.HorizontalButton.Text = 'Horizontal';
            app.UpsideDownButton = uiradiobutton(app.FlippingWaysButtonGroup);
            app.UpsideDownButton.Text = 'Upside Down';
            app.PointProcessingTab = uitab(app.TabGroup);
            app.PointProcessingTab.Title = 'Point Processing';
            app.SelectOperationButtonGroup = uibuttongroup(app.PointProcessingTab);
            app.SelectOperationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectOperationButtonGroupSelectionChanged, true);
            app.SelectOperationButtonGroup.Title = 'Select Operation:';
            app.GammaCorrectionButton = uiradiobutton(app.SelectOperationButtonGroup);
            app.GammaCorrectionButton.Text = 'Gamma Correction';
            app.NoneButton_3 = uiradiobutton(app.SelectOperationButtonGroup);
            app.NoneButton_3.Visible = 'off';
            app.NoneButton_3.Text = 'None';
            app.NoneButton_3.Value = true;
            app.AdjustContrastButton = uiradiobutton(app.SelectOperationButtonGroup);
            app.AdjustContrastButton.Text = 'Adjust Contrast';
            app.AdjustContrastGammaButton = uiradiobutton(app.SelectOperationButtonGroup);
            app.AdjustContrastGammaButton.Text = 'Adjust Contrast + Gamma';
            app.HistEqualizationButton = uiradiobutton(app.SelectOperationButtonGroup);
            app.HistEqualizationButton.Text = 'Hist Equalization';
            app.InsertValuesPanel = uipanel(app.PointProcessingTab);
            app.InsertValuesPanel.Title = 'Insert Values:';
            app.TextArea = uitextarea(app.InsertValuesPanel);
            app.TextArea.Value = {'Input Range:'};
            app.TextArea_2 = uitextarea(app.InsertValuesPanel);
            app.TextArea_2.Value = {'Output Range:'};
            app.LowLabel = uilabel(app.InsertValuesPanel);
            app.LowLabel.Text = 'Low ';
            app.inputlow = uieditfield(app.InsertValuesPanel, 'numeric');
            app.HighLabel = uilabel(app.InsertValuesPanel);
            app.HighLabel.Text = 'High ';
            app.outputHigh = uieditfield(app.InsertValuesPanel, 'numeric');
            app.outputHigh.Value = 1;
            app.GammaEditFieldLabel = uilabel(app.InsertValuesPanel);
            app.GammaEditFieldLabel.Text = 'Gamma ';
            app.GammaEditField = uieditfield(app.InsertValuesPanel, 'numeric');
            app.GammaEditField.ValueChangedFcn = createCallbackFcn(app, @GammaEditFieldValueChanged, true);
            app.HighLabel_2 = uilabel(app.InsertValuesPanel);
            app.HighLabel_2.Text = 'High ';
            app.inputhigh = uieditfield(app.InsertValuesPanel, 'numeric');
            app.inputhigh.Value = 1;
            app.LowLabel_2 = uilabel(app.InsertValuesPanel);
            app.LowLabel_2.Text = 'Low ';
            app.outputLow = uieditfield(app.InsertValuesPanel, 'numeric');
            app.TextArea_3 = uitextarea(app.InsertValuesPanel);
            app.TextArea_3.Value = {'Press "Enter"  to Apply Gamma Correction'};
            app.NeighbourhoodProcessingTab = uitab(app.TabGroup);
            app.NeighbourhoodProcessingTab.Title = 'Neighbourhood Processing';
            app.DropDown = uidropdown(app.NeighbourhoodProcessingTab);
            app.DropDown.Items = {'Unsharp Masking', 'Edge Detection', 'Non-Linear Filter', 'Blurring', 'Add Noise'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Unsharp Masking';
            app.Slider = uislider(app.NeighbourhoodProcessingTab);
            app.Slider.Limits = [1 23];
            app.Slider.MajorTicks = [1 3 5 7 9 11 13 15 17 19 21 23];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 1;
            app.FilterSizeButtonGroup = uibuttongroup(app.NeighbourhoodProcessingTab);
            app.FilterSizeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FilterSizeButtonGroupSelectionChanged, true);
            app.FilterSizeButtonGroup.Title = 'Filter Size:';
            app.LargeButton = uiradiobutton(app.FilterSizeButtonGroup);
            app.LargeButton.Text = 'Large';
            app.SmallButton = uiradiobutton(app.FilterSizeButtonGroup);
            app.SmallButton.Text = 'Small';
            app.NoneButton_2 = uiradiobutton(app.FilterSizeButtonGroup);
            app.NoneButton_2.Visible = 'off';
            app.NoneButton_2.Text = 'None';
            app.NoneButton_2.Value = true;
            app.Slider2_2 = uislider(app.NeighbourhoodProcessingTab);
            app.Slider2_2.Limits = [53 233];
            app.Slider2_2.MajorTicks = [53 73 93 113 133 153 173 193 213 233];
            app.Slider2_2.ValueChangedFcn = createCallbackFcn(app, @Slider2_2ValueChanged, true);
            app.Slider2_2.Value = 53;
            app.NoiseTypeButtonGroup = uibuttongroup(app.NeighbourhoodProcessingTab);
            app.NoiseTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NoiseTypeButtonGroupSelectionChanged, true);
            app.NoiseTypeButtonGroup.Title = 'Noise Type';
            app.NoneButton = uiradiobutton(app.NoiseTypeButtonGroup);
            app.NoneButton.Visible = 'off';
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.GaussianButton = uiradiobutton(app.NoiseTypeButtonGroup);
            app.GaussianButton.Text = 'Gaussian';
            app.SpeckleButton = uiradiobutton(app.NoiseTypeButtonGroup);
            app.SpeckleButton.Text = 'Speckle';
            app.SaltPepperButton = uiradiobutton(app.NoiseTypeButtonGroup);
            app.SaltPepperButton.Text = 'Salt & Pepper';
            app.PeriodicButton = uiradiobutton(app.NoiseTypeButtonGroup);
            app.PeriodicButton.Text = 'Periodic';
            app.AlphaValueSlider = uislider(app.NeighbourhoodProcessingTab);
            app.AlphaValueSlider.Limits = [0 1];
            app.AlphaValueSlider.ValueChangedFcn = createCallbackFcn(app, @AlphaValueSliderValueChanged, true);
            app.TransformationsTab = uitab(app.TabGroup);
            app.TransformationsTab.Title = 'Transformations';
            app.FourierTransformDropDownLabel = uilabel(app.TransformationsTab);
            app.FourierTransformDropDownLabel.Text = 'Fourier Transform';
            app.FourierTransformDropDown = uidropdown(app.TransformationsTab);
            app.FourierTransformDropDown.Items = {'FT', 'FT Shift', 'FT Inverse'};
            app.FourierTransformDropDown.Value = 'FT';
            app.RevertButton = uibutton(app.Image_Processing_AppUIFigure, 'push');
            app.RevertButton.ButtonPushedFcn = createCallbackFcn(app, @RevertButtonPushed, true);
            app.RevertButton.Text = 'Revert';
            % Show the figure after all components are created
            app.Image_Processing_AppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_dip_final_Dana

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Image_Processing_AppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Image_Processing_AppUIFigure)
        end
    end
end